classdef pygellan_stitch_hydrogel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Menu                            matlab.ui.container.Menu
        loadlistMenu                    matlab.ui.container.Menu
        ListBox                         matlab.ui.control.ListBox
        ListBoxLabel                    matlab.ui.control.Label
        MessageEditField                matlab.ui.control.EditField
        MessageEditFieldLabel           matlab.ui.control.Label
        StatusLamp                      matlab.ui.control.Lamp
        StatusLampLabel                 matlab.ui.control.Label
        imflatfield_sigmaEditField      matlab.ui.control.NumericEditField
        imflatfield_sigmaEditFieldLabel  matlab.ui.control.Label
        size_range_maxEditField         matlab.ui.control.NumericEditField
        size_range_maxEditFieldLabel    matlab.ui.control.Label
        size_range_minEditField         matlab.ui.control.NumericEditField
        size_range_minEditFieldLabel    matlab.ui.control.Label
        overlapEditField                matlab.ui.control.NumericEditField
        overlapEditFieldLabel           matlab.ui.control.Label
        imadjustEditField               matlab.ui.control.NumericEditField
        imadjustEditFieldLabel          matlab.ui.control.Label
        edgeEditField                   matlab.ui.control.NumericEditField
        edgeEditFieldLabel              matlab.ui.control.Label
        submitButton                    matlab.ui.control.Button
        metricEditField                 matlab.ui.control.NumericEditField
        metricEditFieldLabel            matlab.ui.control.Label
        sensitivityEditField            matlab.ui.control.NumericEditField
        sensitivityEditFieldLabel       matlab.ui.control.Label
        filenameEditField               matlab.ui.control.EditField
        filenameEditFieldLabel          matlab.ui.control.Label
        openmagellanButton              matlab.ui.control.StateButton
        magellanfilepathEditField       matlab.ui.control.EditField
        magellanfilepathEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
    end
    
    properties (Access = public)
        stitch;%stitch_532=evalin('base','stitch_532');
        data_path;%data_path=evalin('base','data_path');
        pygellan;%pygellan=evalin('base','pygellan');
        parameter;
        parameter_file;
    end
    methods (Access=public)
        function stitch_image(app,data_path)
            if ~isequal(data_path,0)
                %filename=extractAfter(data_path,max(strfind(data_path,pathsep)));
                [~,filename,~]=fileparts(data_path);
                app.filenameEditField.Value=filename;
                file_exist=exist(app.parameter_file,'file');
                if file_exist>0
                    load(app.parameter_file);
                    app.parameter=parameter;
                    app.sensitivityEditField.Value=parameter.sensitivity;
                    app.edgeEditField.Value=parameter.edgethreshold;
                    app.size_range_minEditField.Value=parameter.sizerange(1);
                    app.size_range_maxEditField.Value=parameter.sizerange(2);
                    app.metricEditField.Value=parameter.metricthreshold;
                    app.imadjustEditField.Value=parameter.imadjust;
                    app.overlapEditField.Value=parameter.radii_overlap;
                    app.imflatfield_sigmaEditField.Value=app.parameter.sigma;
                else
                    app.parameter.sigma=app.imflatfield_sigmaEditField.Value;
                end

                % Change the status lamp to red.
                app.StatusLamp.Color = 'red';
                % Update the message field
                app.MessageEditField.Value = 'Stitch is now processing....';

                [app.stitch,app.pygellan]=...
                    import_main(data_path,app.parameter.sigma);
                app.parameter_file=fullfile(data_path,'parameter.mat');
            end
            % Change the status lamp to green.
            app.StatusLamp.Color = 'green';
            app.MessageEditField.Value = 'Stitch is done! Please push the submit button.';
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: openmagellanButton
        function openmagellanButtonValueChanged(app, event)
            close all;
            % Change the color of StatusLamp to yellow.
            app.StatusLamp.Color = 'yellow';
            app.MessageEditField.Value = 'Ready to start the stitching.';
            
            %value = app.openmagellanButton.Value;
                data_path=uigetdir(app.data_path);
                %app.data_path=data_path;
                app.magellanfilepathEditField.Value=data_path;
                stitch_image(app,data_path);

        end

        % Button pushed function: submitButton
        function submitButtonPushed(app, event)

                    close all;

                parameter.sensitivity=double(app.sensitivityEditField.Value);
                parameter.edgethreshold=app.edgeEditField.Value;
                parameter.sizerange=[app.size_range_minEditField.Value app.size_range_maxEditField.Value];
                parameter.metricthreshold=double(app.metricEditField.Value);
                parameter.imadjust=double(app.imadjustEditField.Value);
                parameter.radii_overlap=double(app.overlapEditField.Value);
                parameter.sigma=app.imflatfield_sigmaEditField.Value;
            if numel(app.ListBox.Items)==0
                parameter.iz_max=int16(app.pygellan.iz_max);
            % Change the status lamp to red.
                app.StatusLamp.Color = 'red';
                app.MessageEditField.Value = 'Detection of hydrigel is now processing....';
                parameter.data_path=app.magellanfilepathEditField.Value;
                parameter.fcsname=app.filenameEditField.Value;
                app.parameter=parameter;
                save(app.parameter_file,'parameter');
                hydrogel_main1_detection(app.parameter,app.stitch,app.pygellan);
            else
                for icnt=1:numel(app.ListBox.Items)
                    filename=char(app.ListBox.Items(icnt));
                    parameter.data_path=char(fullfile(app.magellanfilepathEditField.Value,filename));
                    parameter.fcsname=filename;
                    % Change the color of StatusLamp to yellow.
                    app.StatusLamp.Color = 'yellow';
                    app.MessageEditField.Value = 'Ready to start the stitching.';

                    %value = app.openmagellanButton.Value;
                    data_path=parameter.data_path;
                    stitch_image(app,data_path);
                    parameter.iz_max=int16(app.pygellan.iz_max);

                    % Change the status lamp to red.
                    app.StatusLamp.Color = 'red';
                    app.MessageEditField.Value = 'Detection of hydrigel is now processing....';
                    app.parameter=parameter;
                    save(app.parameter_file,'parameter');
                    hydrogel_main1_detection(app.parameter,app.stitch,app.pygellan);

                end
                app.ListBox.Items={};

            end

                app.StatusLamp.Color = 'green';
                app.MessageEditField.Value = 'Detection is done! fcs file is exported.';
        end

        % Menu selected function: loadlistMenu
        function loadlistMenuSelected(app, event)
            [data_path,path]=uigetfile('*.txt');
            if ~isequal(data_path,0)
                dirlist = textread(fullfile(path,data_path),'%s');
                app.magellanfilepathEditField.Value=path;
                app.ListBox.Items=dirlist;
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.loadlistMenu = uimenu(app.Menu);
            app.loadlistMenu.MenuSelectedFcn = createCallbackFcn(app, @loadlistMenuSelected, true);
            app.loadlistMenu.Text = 'load list';
            app.magellanfilepathEditFieldLabel = uilabel(app.UIFigure);
            app.magellanfilepathEditFieldLabel.Text = 'magellan file path';
            app.magellanfilepathEditField = uieditfield(app.UIFigure, 'text');
            app.openmagellanButton = uibutton(app.UIFigure, 'state');
            app.openmagellanButton.ValueChangedFcn = createCallbackFcn(app, @openmagellanButtonValueChanged, true);
            app.openmagellanButton.Text = 'open magellan';
            app.filenameEditFieldLabel = uilabel(app.UIFigure);
            app.filenameEditFieldLabel.Text = 'file name';
            app.filenameEditField = uieditfield(app.UIFigure, 'text');
            app.sensitivityEditFieldLabel = uilabel(app.UIFigure);
            app.sensitivityEditFieldLabel.Text = 'sensitivity';
            app.sensitivityEditField = uieditfield(app.UIFigure, 'numeric');
            app.sensitivityEditField.Value = 0.9;
            app.metricEditFieldLabel = uilabel(app.UIFigure);
            app.metricEditFieldLabel.Text = 'metric';
            app.metricEditField = uieditfield(app.UIFigure, 'numeric');
            app.metricEditField.Value = 0.01;
            app.submitButton = uibutton(app.UIFigure, 'push');
            app.submitButton.ButtonPushedFcn = createCallbackFcn(app, @submitButtonPushed, true);
            app.submitButton.Text = 'submit';
            app.edgeEditFieldLabel = uilabel(app.UIFigure);
            app.edgeEditFieldLabel.Text = 'edge';
            app.edgeEditField = uieditfield(app.UIFigure, 'numeric');
            app.edgeEditField.Value = 0.1;
            app.imadjustEditFieldLabel = uilabel(app.UIFigure);
            app.imadjustEditFieldLabel.Text = 'imadjust';
            app.imadjustEditField = uieditfield(app.UIFigure, 'numeric');
            app.imadjustEditField.Value = 0.06;
            app.overlapEditFieldLabel = uilabel(app.UIFigure);
            app.overlapEditFieldLabel.Text = 'overlap';
            app.overlapEditField = uieditfield(app.UIFigure, 'numeric');
            app.overlapEditField.Value = 0.5;
            app.size_range_minEditFieldLabel = uilabel(app.UIFigure);
            app.size_range_minEditFieldLabel.Text = 'size_range_min';
            app.size_range_minEditField = uieditfield(app.UIFigure, 'numeric');
            app.size_range_minEditField.Value = 10;
            app.size_range_maxEditFieldLabel = uilabel(app.UIFigure);
            app.size_range_maxEditFieldLabel.Text = 'size_range_max';
            app.size_range_maxEditField = uieditfield(app.UIFigure, 'numeric');
            app.size_range_maxEditField.Value = 30;
            app.imflatfield_sigmaEditFieldLabel = uilabel(app.UIFigure);
            app.imflatfield_sigmaEditFieldLabel.Text = 'imflatfield_sigma';
            app.imflatfield_sigmaEditField = uieditfield(app.UIFigure, 'numeric');
            app.imflatfield_sigmaEditField.Value = 80;
            app.StatusLampLabel = uilabel(app.UIFigure);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.UIFigure);
            app.MessageEditFieldLabel = uilabel(app.UIFigure);
            app.MessageEditFieldLabel.Text = 'Message';
            app.MessageEditField = uieditfield(app.UIFigure, 'text');
            app.MessageEditField.Value = 'Please open magellan file.';
            app.ListBoxLabel = uilabel(app.UIFigure);
            app.ListBoxLabel.Text = 'List Box';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {};
            app.ListBox.Value = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pygellan_stitch_hydrogel

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end